classdef ConfigurationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IPAddressTargetLabel            matlab.ui.control.Label
        SSHConfigurationLabel           matlab.ui.control.Label
        SaveandCheckConnectionsButton   matlab.ui.control.Button
        SaveandCloseButton              matlab.ui.control.Button
        DeploymentPathonTargetEditField  matlab.ui.control.EditField
        DeploymentPathonTargetEditFieldLabel  matlab.ui.control.Label
        ExternalModeConfigurationLabel  matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        LabelPrivateKey                 matlab.ui.control.Label
        TargetDropDown                  matlab.ui.control.DropDown
        TargetDropDownLabel             matlab.ui.control.Label
        TargetPortEditField             matlab.ui.control.EditField
        TargetPortEditFieldLabel        matlab.ui.control.Label
        LocalPortEditField              matlab.ui.control.EditField
        LocalPortEditFieldLabel         matlab.ui.control.Label
        UserTargetEditField             matlab.ui.control.EditField
        UserTargetEditFieldLabel        matlab.ui.control.Label
        UserBuildHostEditField          matlab.ui.control.EditField
        UserBuildHostEditFieldLabel     matlab.ui.control.Label
        IPAddressBuildHostEditField     matlab.ui.control.EditField
        IPAddressBuildHostEditFieldLabel  matlab.ui.control.Label
        UserJumpHostEditField           matlab.ui.control.EditField
        UserJumpHostLabel               matlab.ui.control.Label
        IPAddressJumpHostEditField      matlab.ui.control.EditField
        IPAddressJumpHostEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
         % Description
         config;
         appDir;
         IPAddressesTargets;
    end
    
    methods (Access = private)
        
        function saveConfig(app)
            % save current configuration as .mat file
            app.getConfigFields();
            cfg = app.config;
            p = 'configuration/config.json';
            cfgJson = jsonencode(app.config, "PrettyPrint", true);
            fid = fopen(p, 'w');
            fwrite(fid, cfgJson);
            fclose(fid);
            disp(sprintf('config saved: %s', p));
        end

        function loadConfig(app)
            % load configuration from .mat config file
            app.config = jsondecode(fileread('config.json'));
        end
        
        function getConfigFields(app)
            % read values of text fields
            app.config.IPJumpHost = app.IPAddressJumpHostEditField.Value;
            app.config.UserJumpHost = app.UserJumpHostEditField.Value;
            app.config.IPBuildHost = app.IPAddressBuildHostEditField.Value;
            app.config.UserBuildHost = app.UserBuildHostEditField.Value;
            app.config.IPTarget = app.IPAddressTargetLabel.Text;
            app.config.UserTarget = app.UserTargetEditField.Value;
            app.config.PortLocal = app.LocalPortEditField.Value;
            app.config.PortTarget = app.TargetPortEditField.Value;
            app.config.DeploymentPath = app.DeploymentPathonTargetEditField.Value;
            app.config.PrivateKey = app.LabelPrivateKey.Text;
        end
        
        function setConfigFields(app)
            % write values to text fields
            app.IPAddressJumpHostEditField.Value = app.config.IPJumpHost;
            app.UserJumpHostEditField.Value = app.config.UserJumpHost;
            app.IPAddressBuildHostEditField.Value = app.config.IPBuildHost;
            app.UserBuildHostEditField.Value = app.config.UserBuildHost;
            app.IPAddressTargetLabel.Text = app.config.IPTarget;
            app.UserTargetEditField.Value = app.config.UserTarget;
            app.LocalPortEditField.Value = app.config.PortLocal;
            app.TargetPortEditField.Value = app.config.PortTarget;
            app.DeploymentPathonTargetEditField.Value = app.config.DeploymentPath;
            app.LabelPrivateKey.Text = app.config.PrivateKey;
        end     
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load configuration file
            hosts = jsondecode(fileread('hosts.json'));
            app.TargetDropDown.Items = hosts.targetNames;
            app.IPAddressesTargets = hosts.targetIPAddresses;
            app.loadConfig();
            app.setConfigFields();
            % set the value of the targets drop down menu
            idxTarget = find(strcmp(app.IPAddressesTargets, app.config.IPTarget));
            if (idxTarget)
                app.TargetDropDown.ValueIndex = idxTarget;
            end
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            if item
                switch item
                    case 1
                       % use existing key
                       [file, location] = uigetfile('*.*', 'Select a private key file', ['C:\Users\' getenv('username') '\.ssh\']);
                       if file
                           if contains(file, '.pub')
                               sldiagviewer.reportInfo(sprintf("### Error: %s is a public key - please select a private key.\n", file));
                           else
                               app.config.PrivateKey = [location file];
                               app.LabelPrivateKey.Text = app.config.PrivateKey;
                           end
                       end
                    case 2
                        setupKeys('generateKey');
                    case 3
                        app.saveConfig();
                        setupKeys('copyPublicKeyToHosts');
                    otherwise
                end
            end         
        end

        % Button pushed function: SaveandCloseButton
        function SaveandCloseButtonPushed(app, event)
            app.saveConfig();
            app.delete();
        end

        % Button pushed function: SaveandCheckConnectionsButton
        function SaveandCheckConnectionsButtonPushed(app, event)
            app.saveConfig();
            checkConnection;
        end

        % Clicked callback: TargetDropDown
        function TargetDropDownClicked(app, event)
            app.config.IPTarget = app.IPAddressesTargets{app.TargetDropDown.ValueIndex};
            app.IPAddressTargetLabel.Text = app.config.IPTarget;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IPAddressJumpHostEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddressJumpHostEditFieldLabel.Text = 'IP-Address Jump Host';
            app.IPAddressJumpHostEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddressJumpHostEditField.Tooltip = {'Global IP-address of jump host.'};
            app.UserJumpHostLabel = uilabel(app.UIFigure);
            app.UserJumpHostLabel.Text = 'User Jump Host';
            app.UserJumpHostEditField = uieditfield(app.UIFigure, 'text');
            app.UserJumpHostEditField.Tooltip = {'Username for log-in on jump host (NT-User).'};
            app.IPAddressBuildHostEditFieldLabel = uilabel(app.UIFigure);
            app.IPAddressBuildHostEditFieldLabel.Text = 'IP-Address Build Host';
            app.IPAddressBuildHostEditField = uieditfield(app.UIFigure, 'text');
            app.IPAddressBuildHostEditField.Tooltip = {'Global IP-address of build host.'};
            app.UserBuildHostEditFieldLabel = uilabel(app.UIFigure);
            app.UserBuildHostEditFieldLabel.Text = 'User Build Host';
            app.UserBuildHostEditField = uieditfield(app.UIFigure, 'text');
            app.UserBuildHostEditField.Tooltip = {'Username for log-in on build host.'};
            app.UserTargetEditFieldLabel = uilabel(app.UIFigure);
            app.UserTargetEditFieldLabel.Text = 'User Target';
            app.UserTargetEditField = uieditfield(app.UIFigure, 'text');
            app.UserTargetEditField.Tooltip = {'Username for log-in on target machine.'};
            app.LocalPortEditFieldLabel = uilabel(app.UIFigure);
            app.LocalPortEditFieldLabel.Text = 'Local Port';
            app.LocalPortEditField = uieditfield(app.UIFigure, 'text');
            app.LocalPortEditField.Tooltip = {'Port on development PC used for local port forwarding.'};
            app.TargetPortEditFieldLabel = uilabel(app.UIFigure);
            app.TargetPortEditFieldLabel.Text = 'Target Port';
            app.TargetPortEditField = uieditfield(app.UIFigure, 'text');
            app.TargetPortEditField.Tooltip = {'Port on target machine used for external mode communication.'};
            app.TargetDropDownLabel = uilabel(app.UIFigure);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.UIFigure);
            app.TargetDropDown.Items = {'Option 1'};
            app.TargetDropDown.Tooltip = {'Select target. List of available targets defined in hosts.json.'};
            app.TargetDropDown.ClickedFcn = createCallbackFcn(app, @TargetDropDownClicked, true);
            app.LabelPrivateKey = uilabel(app.UIFigure);
            app.LabelPrivateKey.Tooltip = {'Path to private key file for SSH connections.'};
            app.LabelPrivateKey.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Select private key...', 'Generate key pair...', 'Copy public key to hosts...'};
            app.DropDown.Tooltip = {'Select private key file for SSH connections.'};
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'Select private key...';
            app.ExternalModeConfigurationLabel = uilabel(app.UIFigure);
            app.ExternalModeConfigurationLabel.Text = 'External Mode Configuration:';
            app.DeploymentPathonTargetEditFieldLabel = uilabel(app.UIFigure);
            app.DeploymentPathonTargetEditFieldLabel.Text = 'Deployment Path on Target';
            app.DeploymentPathonTargetEditField = uieditfield(app.UIFigure, 'text');
            app.DeploymentPathonTargetEditField.Tooltip = {'Deployment path of application on target machine.'};
            app.SaveandCloseButton = uibutton(app.UIFigure, 'push');
            app.SaveandCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandCloseButtonPushed, true);
            app.SaveandCloseButton.Tooltip = {'Save and close configuration app.'};
            app.SaveandCloseButton.Text = 'Save and Close';
            app.SaveandCheckConnectionsButton = uibutton(app.UIFigure, 'push');
            app.SaveandCheckConnectionsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandCheckConnectionsButtonPushed, true);
            app.SaveandCheckConnectionsButton.Tooltip = {'Save configuration and check connection to jump host, build host and target.'};
            app.SaveandCheckConnectionsButton.Text = 'Save and Check Connections...';
            app.SSHConfigurationLabel = uilabel(app.UIFigure);
            app.SSHConfigurationLabel.Text = 'SSH Configuration:';
            app.IPAddressTargetLabel = uilabel(app.UIFigure);
            app.IPAddressTargetLabel.Tooltip = {'Private IP-address of target.'};
            app.IPAddressTargetLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigurationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end